classdef projectileMotionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        PlotButton                     matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        InputsLabel                    matlab.ui.control.Label
        x0EditFieldLabel               matlab.ui.control.Label
        x0EditField                    matlab.ui.control.EditField
        y0EditFieldLabel               matlab.ui.control.Label
        y0EditField                    matlab.ui.control.EditField
        InitialVelocityEditFieldLabel  matlab.ui.control.Label
        InitialVelocityEditField       matlab.ui.control.EditField
        AngleEditFieldLabel            matlab.ui.control.Label
        AngleEditField                 matlab.ui.control.EditField
        GravityListBoxLabel            matlab.ui.control.Label
        GravityListBox                 matlab.ui.control.ListBox
        OutputsLabel                   matlab.ui.control.Label
        MaxHeightEditFieldLabel        matlab.ui.control.Label
        MaxHeightEditField             matlab.ui.control.EditField
        MaxRangeEditFieldLabel         matlab.ui.control.Label
        MaxRangeEditField              matlab.ui.control.EditField
        PeakLocationEditFieldLabel     matlab.ui.control.Label
        PeakLocationEditField          matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: x0EditField
        function x0EditFieldValueChanged(app, event)
%             value = app.x0EditField.Value;
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            x0 = str2double(app.x0EditField.Value);
            y0 = str2double(app.y0EditField.Value);
            v0 = str2double(app.InitialVelocityEditField.Value);
            iangle = str2double(app.AngleEditField.Value);
            
            switch app.GravityListBox.Value
                case 'Earth'
                    g = 9.81;
                case 'Moon' 
                    g = 1.6;
                case 'Mars'
                    g = 3.71;
                case 'Jupiter'
                    g = 24.79;
                case 'Pluto'
                    g = 0.62;
            end
            
a = iangle*(pi / 180);  %degree to angle conversion

xm = (v0.^2)./g*sin(2*a);
xs = xm./100;

x = x0:xs:xm;
y = (x*tan(a) - g/(2*(v0.^2)*(cos(a).^2))*x.^2) + y0;

for n = 1:length(x)
    if g== 9.81;
        h = plot(app.UIAxes, x(n), y(n), 'b.');
    elseif g == 1.6;
        h = plot(app.UIAxes, x(n), y(n), 'r.');
    elseif g == 3.71;
        h = plot(app.UIAxes, x(n), y(n), 'm.');
    elseif g == 24.79;
        h = plot(app.UIAxes, x(n), y(n), 'k.');
    end
    
    hold(app.UIAxes,'on'); 
%     axis(app.UIAxes, 'tight');
    set(h, 'linewidth', 30);
    pause(0.001);
        
end


maxheight = max(y);

maxpoint = find( y == max(y(:)));
xhigh = x(maxpoint);

h2 = plot(app.UIAxes, x(maxpoint), y(maxpoint), 'g.');
set(h2, 'Markersize', 15);
hold(app.UIAxes, 'on');

app.MaxHeightEditField.Value = num2str(maxheight);
app.MaxRangeEditField.Value = num2str(xm);
app.PeakLocationEditField.Value = num2str(xhigh);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            cla(app.UIAxes);
            app.MaxHeightEditField.Value = num2str(0);
            app.MaxRangeEditField.Value = num2str(0);
            app.PeakLocationEditField.Value = num2str(0);
            app.x0EditField.Value = num2str(0);
            app.y0EditField.Value = num2str(0);
            app.InitialVelocityEditField.Value = num2str(0);
            app.AngleEditField.Value = num2str(0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Projectile Trajectory')
            xlabel(app.UIAxes, 'Distance')
            ylabel(app.UIAxes, 'Height')
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InputsLabel = uilabel(app.UIFigure);
            app.InputsLabel.Text = 'Inputs';
            app.x0EditFieldLabel = uilabel(app.UIFigure);
            app.x0EditFieldLabel.Text = 'x0';
            app.x0EditField = uieditfield(app.UIFigure, 'text');
            app.x0EditField.ValueChangedFcn = createCallbackFcn(app, @x0EditFieldValueChanged, true);
            app.y0EditFieldLabel = uilabel(app.UIFigure);
            app.y0EditFieldLabel.Text = 'y0';
            app.y0EditField = uieditfield(app.UIFigure, 'text');
            app.InitialVelocityEditFieldLabel = uilabel(app.UIFigure);
            app.InitialVelocityEditFieldLabel.Text = 'Initial Velocity';
            app.InitialVelocityEditField = uieditfield(app.UIFigure, 'text');
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'text');
            app.GravityListBoxLabel = uilabel(app.UIFigure);
            app.GravityListBoxLabel.Text = 'Gravity';
            app.GravityListBox = uilistbox(app.UIFigure);
            app.GravityListBox.Items = {'Earth', 'Moon', 'Mars', 'Jupiter'};
            app.GravityListBox.Value = 'Earth';
            app.OutputsLabel = uilabel(app.UIFigure);
            app.OutputsLabel.Text = 'Outputs';
            app.MaxHeightEditFieldLabel = uilabel(app.UIFigure);
            app.MaxHeightEditFieldLabel.Text = 'Max Height';
            app.MaxHeightEditField = uieditfield(app.UIFigure, 'text');
            app.MaxRangeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRangeEditFieldLabel.Text = 'Max Range';
            app.MaxRangeEditField = uieditfield(app.UIFigure, 'text');
            app.PeakLocationEditFieldLabel = uilabel(app.UIFigure);
            app.PeakLocationEditFieldLabel.Text = 'Peak Location';
            app.PeakLocationEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = projectileMotionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end